classdef main_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        LoadFileButton      matlab.ui.control.Button
        FileEditField       matlab.ui.control.EditField
        FileEditFieldLabel  matlab.ui.control.Label
        ExponentialButton   matlab.ui.control.Button
        SawtoothButton      matlab.ui.control.Button
        SquareButton        matlab.ui.control.Button
        ParabolaButton      matlab.ui.control.Button
        thetan              matlab.ui.control.UIAxes
        fun                 matlab.ui.control.UIAxes
        cns                 matlab.ui.control.UIAxes
        cosines             matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        functionResult
    end
    
    methods (Access = private)
        function [funRes, funcTimes] = createFunc(app, min, max, center, f, period, inc)
            funRes = zeros(round((center - min + max - center)),1);
            funcTimes = zeros(length(funRes),1);
            point = findStartPoint(app, min, center, period);
            for i = 0:round((center - min + max - center) / period)
                for time = point - period/2 : inc : point + period / 2
                    index = round(i * period / inc + (time - (point - period/2)) / inc) + 1;
                    funcTimes(index) = time;
                    funRes(index) = f(time - point);
                end
                point = point + period;
            end
        end
        function point = findStartPoint(~, old, center, period)
            point = center;
            
            while (abs(point - old) > period + 0.001)
               point = point - period; 
            end
            fprintf("Next period is %f\n", point);
        end
        function plotFunction(app, min, max, period, inc, func, numNs, center)
            cla(app.cosines);
            cla(app.cns);
            cla(app.thetan);
            cla(app.fun);
            w0 = 2 * pi / period;
            [funRes, funcTimes] = createFunc(app, min, max, center, func, period, inc);
            cn = zeros(numNs, 1);
            on = zeros(numNs, 1);
            s = size(funcTimes);
            funSeries = zeros(s(1),1);
            hold(app.cosines, "on");
            hold(app.cns, "on");
            hold(app.thetan, "on");
            average = mean(funRes);
            funSeries = funSeries + average;
            for n = 1:numNs
                [an, bn] = Series(funcTimes, funRes, n, w0);
                cosine = zeros(s(1), 1);
                cn(n) = sqrt(an^2 + bn^2);
                on(n) = atan2(-bn,an);
                fprintf("Sized = %d\n", s(1));
                for i = 1:s(1)
                    t = funcTimes(i);
                    cosine(i) = cn(n)*cos(w0*n*t + on(n));
                    funSeries(i) = funSeries(i) + cosine(i);
                end
                fprintf("Plotting n = %d\n", n);
                cla(app.fun);
                plot(app.fun, funcTimes, funSeries);
                plot(app.cosines, funcTimes, cosine);
                plot(app.thetan, n, on(n));
                stem(app.cns, n, cn(n));
                pause(0.1);
            end
            hold(app.fun, 'on');
            plot(app.fun, funcTimes, funRes);
            hold(app.fun, 'off');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ParabolaButton
        function ParabolaButtonPushed(app, event)
            max = 3;
            min = -max;
            period = 2*pi;
            inc = 0.001;
            parabola = @(t) (sin(t));
            NUM_Ns = 20;
            center = 0.25;
            plotFunction(app, min, max, period, inc, parabola, NUM_Ns, center);
        end

        % Button pushed function: SquareButton
        function SquareButtonPushed(app, event)
            max = 3;
            min = -max;
            period = 2;
            inc = 0.001;
            parabola = @(t) ((t-0.25)>0);
            NUM_Ns = 20;
            center = 0;
            plotFunction(app, min, max, period, inc, parabola, NUM_Ns, center);
        end

        % Button pushed function: SawtoothButton
        function SawtoothButtonPushed(app, event)
            max = 3;
            min = -max;
            period = 2;
            inc = 0.001;
            parabola = @(t) (t);
            NUM_Ns = 20;
            center = 0;
            plotFunction(app, min, max, period, inc, parabola, NUM_Ns, center);            
        end

        % Button pushed function: ExponentialButton
        function ExponentialButtonPushed(app, event)
            max = 2;
            min = -max;
            period = 1;
            inc = 0.0001;
            parabola = @(t) (exp(t));
            NUM_Ns = 20;
            center = 0;
            plotFunction(app, min, max, period, inc, parabola, NUM_Ns, center);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.cosines = uiaxes(app.UIFigure);
            title(app.cosines, 'All Cosines')
            xlabel(app.cosines, 't')
            ylabel(app.cosines, 'value')
            zlabel(app.cosines, 'Z')
            app.cosines.Tag = 'cosines';
            app.cns = uiaxes(app.UIFigure);
            title(app.cns, 'Cn')
            xlabel(app.cns, 'N')
            ylabel(app.cns, 'value')
            zlabel(app.cns, 'Z')
            app.fun = uiaxes(app.UIFigure);
            title(app.fun, 'Fourier Function')
            xlabel(app.fun, 't')
            ylabel(app.fun, 'value')
            zlabel(app.fun, 'Z')
            app.thetan = uiaxes(app.UIFigure);
            title(app.thetan, '\thetan')
            xlabel(app.thetan, 'N')
            ylabel(app.thetan, 'value')
            zlabel(app.thetan, 'Z')
            app.ParabolaButton = uibutton(app.UIFigure, 'push');
            app.ParabolaButton.ButtonPushedFcn = createCallbackFcn(app, @ParabolaButtonPushed, true);
            app.ParabolaButton.Text = 'Parabola';
            app.SquareButton = uibutton(app.UIFigure, 'push');
            app.SquareButton.ButtonPushedFcn = createCallbackFcn(app, @SquareButtonPushed, true);
            app.SquareButton.Text = 'Square';
            app.SawtoothButton = uibutton(app.UIFigure, 'push');
            app.SawtoothButton.ButtonPushedFcn = createCallbackFcn(app, @SawtoothButtonPushed, true);
            app.SawtoothButton.Text = 'Sawtooth';
            app.ExponentialButton = uibutton(app.UIFigure, 'push');
            app.ExponentialButton.ButtonPushedFcn = createCallbackFcn(app, @ExponentialButtonPushed, true);
            app.ExponentialButton.Text = 'Exponential';
            app.FileEditFieldLabel = uilabel(app.UIFigure);
            app.FileEditFieldLabel.Text = 'File';
            app.FileEditField = uieditfield(app.UIFigure, 'text');
            app.LoadFileButton = uibutton(app.UIFigure, 'push');
            app.LoadFileButton.Text = 'Load File';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end